classdef Readme_L_Left < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        HTML        matlab.ui.control.HTML
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. 设置窗口标题
            app.UIFigure.Name = 'L型电路(左) - 负载端并联型 - 高阻负载匹配专家';
            
            % 2. 准备 HTML 内容
            % 【纯离线版】基于 L左型 (并联在负载端) 的物理逻辑重写
            htmlContent = [ ...
                '<!DOCTYPE html>', ...
                '<html><head>', ...
                '<meta charset="utf-8">', ...
                '<style>', ...
                '   body { font-family: "Segoe UI", "Microsoft YaHei", sans-serif; padding: 30px; line-height: 1.6; color: #333; background-color: #fdfdfd; }', ...
                '   .container { max-width: 100%; margin: 0 auto; }', ...
                '   h1 { color: #0072BD; border-bottom: 3px solid #0072BD; padding-bottom: 10px; margin-top: 0; text-align: center; }', ...
                '   h2 { color: #D95319; margin-top: 40px; border-left: 6px solid #D95319; padding-left: 10px; background: #fff5f0; padding-top:5px; padding-bottom:5px;}', ...
                '   h3 { color: #EDB120; margin-top: 25px; font-weight: bold; border-bottom: 1px dashed #ccc; display: inline-block; }', ...
                '   p { margin-bottom: 12px; text-align: justify; }', ...
                '   ul { margin-bottom: 15px; padding-left: 20px; }', ...
                '   li { margin-bottom: 6px; }', ...
                '   /* 信息框样式 */', ...
                '   .box-blue { background-color: #e8f4f8; border: 1px solid #bce8f1; padding: 15px; border-radius: 5px; color: #31708f; margin: 15px 0; }', ...
                '   .box-red { background-color: #f2dede; border: 1px solid #ebccd1; padding: 15px; border-radius: 5px; color: #a94442; margin: 15px 0; }', ...
                '   .box-green { background-color: #dff0d8; border: 1px solid #d6e9c6; padding: 15px; border-radius: 5px; color: #3c763d; margin: 15px 0; }', ...
                '   /* 离线公式样式 */', ...
                '   .math-text { font-family: "Times New Roman", serif; font-style: italic; font-size: 1.1em; padding: 0 2px; }', ...
                '   .math-block { background-color: #fff; padding: 15px; border-radius: 4px; text-align: center; border: 1px solid #eee; margin: 15px 0; overflow-x: auto; font-family: "Times New Roman", serif; font-size: 1.2em; }', ...
                '   /* 分数样式 (CSS 实现) */', ...
                '   .frac { display: inline-block; vertical-align: middle; text-align: center; font-size: 0.9em; margin: 0 5px; }', ...
                '   .frac .num { border-bottom: 1px solid #000; display: block; padding: 0 2px; }', ...
                '   .frac .den { display: block; padding: 0 2px; }', ...
                '   /* 根号样式 */', ...
                '   .sqrt { white-space: nowrap; }', ...
                '   .sqrt span:first-child { font-size: 1.2em; vertical-align: text-bottom; }', ...
                '   .sqrt span:last-child { border-top: 1px solid #000; padding-top: 1px; }', ...
                '</style></head><body>', ...
                '<div class="container">', ...
                ...
                '<h1>L型电路(左) 设计原理详解</h1>', ...
                ...
                '<h2>1. 核心视角转换：高阻抗负载的“降压”匹配</h2>', ...
                '<p>与L型(右)刚好相反，<b>L型(左)</b> 的结构特点是<b>并联元件位于负载端</b> 。</p>', ...
                '<div class="box-blue"><b>⚡ 阻抗高低法则：</b><br>L型网络中，<b>并联元件总是连接在阻抗较高的一端</b>。<br>此处并联电容 <span class="math-text">C<sub>1</sub></span> 接在负载端，这意味着：<br><b>负载电阻 (R<sub>L</sub>) > 源阻抗 (50&Omega;)</b></div>', ...
                '<p>因此，<b>L型(左) 是专门为了将“高阻抗负载”（如 >50Ω）向下变换匹配到 50Ω 源而设计的。</b></p>', ...
                ...
                '<h2>2. 拓扑详解：负载端并联 + 源端串联</h2>', ...
                '<h3>电路构成：</h3>', ...
                '<ul>', ...
                '   <li><b>负载端 (Load, 左侧)：</b>并联一个可变电容 <span class="math-text">C<sub>1</sub></span>。它直接跨接在负载两端。</li>', ...
                '   <li><b>源端 (Source, 右侧)：</b>连接 <b>LC串联支路</b> (<span class="math-text">L + C<sub>2</sub></span>)。</li>', ...
                '   <li><b>连接逻辑：</b>信号从源出发 &rarr; 经过串联支路 &rarr; 到达并联的负载组合。</li>', ...
                '</ul>', ...
                ...
                '<h2>3. 物理原理与数学推导</h2>', ...
                '<h3>为什么负载电阻必须 > 50Ω？</h3>', ...
                '<p>为了实现匹配，我们需要把高阻抗 <span class="math-text">R<sub>L</sub></span> 变换为 <span class="math-text">50&Omega;</span>。</p>', ...
                '<p><b>原理：</b>并联一个电容 <span class="math-text">C<sub>1</sub></span> 可以将负载的<b>等效串联电阻</b>降低。</p>', ...
                '<p>假设负载为纯电阻 <span class="math-text">R<sub>L</sub></span>，并联电容 <span class="math-text">C<sub>1</sub></span> 后，该并联组合的阻抗 <span class="math-text">Z<sub>p</sub></span> 为：</p>', ...
                '<div class="math-block">Z<sub>p</sub> = <div class="frac"><span class="num">R<sub>L</sub></span><span class="den">1 + j&omega;C<sub>1</sub>R<sub>L</sub></span></div></div>', ...
                '<p>将其转换为串联形式 (<span class="math-text">R<sub>s</sub> + jX<sub>s</sub></span>)，其实部 <span class="math-text">R<sub>s</sub></span> 为：</p>', ...
                '<div class="math-block">Re(Z<sub>p</sub>) = <div class="frac"><span class="num">R<sub>L</sub></span><span class="den">1 + (&omega;C<sub>1</sub>R<sub>L</sub>)<sup>2</sup></span></div></div>', ...
                '<p>为了匹配，这个实部必须等于源阻抗 50Ω：</p>', ...
                '<div class="math-block"><div class="frac"><span class="num">R<sub>L</sub></span><span class="den">1 + Q<sup>2</sup></span></div> = 50 &rArr; R<sub>L</sub> = 50(1 + Q<sup>2</sup>)</div>', ...
                '<div class="box-red"><b>结论：</b>由于 <span class="math-text">Q<sup>2</sup> &ge; 0</span>，必然有 <span class="math-text">R<sub>L</sub> &ge; 50&Omega;</span>。<br>如果负载电阻小于 50Ω，并联电容只会让它变得更小，永远无法匹配到 50Ω。</div>', ...
                ...
                '<h2>4. 算法逻辑：反向设计 (求 C1, C2)</h2>', ...
                '<div class="box-green"><b>设计目标：</b>已知高阻负载 <span class="math-text">Z<sub>L</sub></span>，求 <span class="math-text">C<sub>1</sub>, C<sub>2</sub></span>。</div>', ...
                '<ol>', ...
                '   <li><b>步骤一：计算品质因数 Q</b><br>注意与L(右)的区别，分子分母互换：<span class="math-text">Q = <span class="sqrt"><span>&radic;</span><span>R<sub>L</sub>/50 - 1</span></span></span></li>', ...
                '   <li><b>步骤二：计算负载端并联电容 C1</b><br>基于高阻端的 Q 值定义：<span class="math-text">C<sub>1</sub> = <div class="frac"><span class="num">Q</span><span class="den">&omega; &times; R<sub>L</sub></span></div></span></li>', ...
                '   <li><b>步骤三：计算串联支路所需电抗 X<sub>req</sub></b><br>串联支路需要提供感性电抗来抵消并联部分的容性分量。<br>基于低阻端 (50Ω) 的 Q 值定义：<br><span class="math-text">X<sub>req</sub> = + Q &times; 50</span></li>', ...
                '   <li><b>步骤四：计算串联电容 C2</b><br>总串联电抗由 L 和 C2 共同提供：<br><span class="math-text">X<sub>req</sub> = &omega;L - <div class="frac"><span class="num">1</span><span class="den">&omega;C<sub>2</sub></span></div></span><br>求解 C2：<br><div class="math-block">C<sub>2</sub> = <div class="frac"><span class="num">1</span><span class="den">&omega; ( &omega;L - X<sub>req</sub> )</span></div></div></li>', ...
                '</ol>', ...
                ...
                '<h2>5. 总结</h2>', ...
                '<p><b>适用场景：</b>当您的负载阻抗实部很大（例如 100Ω、200Ω）时，必须使用此 <b>L型(左)</b> 拓扑。</p>', ...
                '<p><b>记忆口诀：</b>“高并低串”。<br>负载是高阻,所以说负载端并联电容 (L左)。<br>源端是高阻 (相对低阻负载) 所以说源端并联电容 (L右)。</p>', ...
                '</div></body></html>'];
            app.HTML.HTMLSource = htmlContent;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.HTML = uihtml(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Readme_L_Left

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end