classdef Readme_L_Right < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        HTML        matlab.ui.control.HTML
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % 1. 设置窗口标题
            app.UIFigure.Name = 'L型电路(右) - 详细设计原理与算法逻辑';
            
            % 2. 准备 HTML 内容
            % 【纯离线版】不依赖任何外部 JS 脚本，使用 CSS 绘制公式
            htmlContent = [ ...
                '<!DOCTYPE html>', ...
                '<html><head>', ...
                '<meta charset="utf-8">', ...
                '<style>', ...
                '   body { font-family: "Segoe UI", "Microsoft YaHei", sans-serif; padding: 30px; line-height: 1.6; color: #333; background-color: #fdfdfd; }', ...
                '   .container { max-width: 100%; margin: 0 auto; }', ...
                '   h1 { color: #0072BD; border-bottom: 3px solid #0072BD; padding-bottom: 10px; margin-top: 0; text-align: center; }', ...
                '   h2 { color: #D95319; margin-top: 40px; border-left: 6px solid #D95319; padding-left: 10px; background: #fff5f0; padding-top:5px; padding-bottom:5px;}', ...
                '   h3 { color: #EDB120; margin-top: 25px; font-weight: bold; border-bottom: 1px dashed #ccc; display: inline-block; }', ...
                '   p { margin-bottom: 12px; text-align: justify; }', ...
                '   ul { margin-bottom: 15px; padding-left: 20px; }', ...
                '   li { margin-bottom: 6px; }', ...
                '   /* 信息框样式 */', ...
                '   .box-blue { background-color: #e8f4f8; border: 1px solid #bce8f1; padding: 15px; border-radius: 5px; color: #31708f; margin: 15px 0; }', ...
                '   .box-red { background-color: #f2dede; border: 1px solid #ebccd1; padding: 15px; border-radius: 5px; color: #a94442; margin: 15px 0; }', ...
                '   .box-green { background-color: #dff0d8; border: 1px solid #d6e9c6; padding: 15px; border-radius: 5px; color: #3c763d; margin: 15px 0; }', ...
                '   /* 离线公式样式 */', ...
                '   .math-text { font-family: "Times New Roman", serif; font-style: italic; font-size: 1.1em; padding: 0 2px; }', ...
                '   .math-block { background-color: #fff; padding: 15px; border-radius: 4px; text-align: center; border: 1px solid #eee; margin: 15px 0; overflow-x: auto; font-family: "Times New Roman", serif; font-size: 1.2em; }', ...
                '   /* 分数样式 (CSS 实现) */', ...
                '   .frac { display: inline-block; vertical-align: middle; text-align: center; font-size: 0.9em; margin: 0 5px; }', ...
                '   .frac .num { border-bottom: 1px solid #000; display: block; padding: 0 2px; }', ...
                '   .frac .den { display: block; padding: 0 2px; }', ...
                '   /* 根号样式 */', ...
                '   .sqrt { white-space: nowrap; }', ...
                '   .sqrt span:first-child { font-size: 1.2em; vertical-align: text-bottom; }', ...
                '   .sqrt span:last-child { border-top: 1px solid #000; padding-top: 1px; }', ...
                '</style></head><body>', ...
                '<div class="container">', ...
                ...
                '<h1>L型电路(右) 设计原理详解</h1>', ...
                ...
                '<h2>1. 电路拓扑结构与功能定义</h2>', ...
                '<p>该电路被称为<b>“L型电路(右)”</b>，在微波工程中是一个改进型的L型阻抗匹配网络。其核心目的是实现阻抗变换，确保信号源与负载之间的最大功率传输。</p>', ...
                '<h3>电路构成：</h3>', ...
                '<ul>', ...
                '   <li><b>源端 (Source Side)：</b>并联一个电容 <span class="math-text">C<sub>1</sub></span> 在信源一侧。</li>', ...
                '   <li><b>负载端 (Load Side)：</b>串联一个电感 <span class="math-text">L</span> 和一个可变电容 <span class="math-text">C<sub>2</sub></span> 串联，连接在信源和负载之间。</li>', ...
                '</ul>', ...
                '<div class="box-blue"><b>结构特性：</b>这是一个 <b>Shunt-C (Source) / Series-X (Load)</b> 结构。串联支路引入了 <span class="math-text">L</span> 和 <span class="math-text">C<sub>2</sub></span> 的组合。虽然标准的L型网络通常只有一个串联元件，但引入 <span class="math-text">C<sub>2</sub></span> 构成 LC 串联组合，使得该支路的等效电抗 (<span class="math-text">X<sub>Series</sub></span>) 可在感性和容性之间调节，极大拓宽了匹配范围，优于固定电感的传统设计。</div>', ...
                ...
                '<h2>2. 输入与输出参数说明</h2>', ...
                '<p>根据程序界面，该模块分为两个主要功能区：<b>正向计算（求范围）</b> 和 <b>反向设计（求元件值）</b>。</p>', ...
                '<h3>A. 全局输入参数 (环境参数)</h3>', ...
                '<ul><li><b>工作频率 (<span class="math-text">f</span>)：</b>单位 MHz。这是所有电抗计算的基础 (<span class="math-text">&omega; = 2&pi;f</span>)。</li></ul>', ...
                '<h3>B. 正向计算 (②设置元件参数 -> ③计算范围)</h3>', ...
                '<ul>', ...
                '   <li><b>输入：</b>固定电感 <span class="math-text">L</span> 的值，以及电容 <span class="math-text">C<sub>1</sub>, C<sub>2</sub></span> 的可调范围 (<span class="math-text">min ~ max</span>)。</li>', ...
                '   <li><b>输出：</b>可匹配负载阻抗范围 <span class="math-text">Z<sub>Load</sub> = R<sub>L</sub> + jX<sub>L</sub></span>。即在该频率下，调节 <span class="math-text">C<sub>1</sub></span> 和 <span class="math-text">C<sub>2</sub></span> 能将哪些区域的负载匹配到 50Ω。</li>', ...
                '</ul>', ...
                '<h3>C. 反向设计 (⑤设置负载 -> ⑥计算参考电容)</h3>', ...
                '<ul>', ...
                '   <li><b>输入：</b>目标负载阻抗 <span class="math-text">Z<sub>L</sub> = R<sub>L</sub> + jX<sub>L</sub></span> 和选定的电感值 <span class="math-text">L</span>。</li>', ...
                '   <li><b>输出：</b>所需的匹配元件值：<span class="math-text">C<sub>1</sub></span> 和 <span class="math-text">C<sub>2</sub></span>。</li>', ...
                '</ul>', ...
                ...
                '<h2>3. 工程背景：为什么匹配目标是 50Ω？</h2>', ...
                '<p>该电路的设计目标是将负载阻抗匹配到源阻抗 50Ω。这是一个基于同轴电缆物理特性的工业折中标准：</p>', ...
                '<ul>', ...
                '   <li><b>功率容量优先：</b>同轴电缆在阻抗约为 <b>30Ω</b> 时，耐压能力最强，可传输最大功率。</li>', ...
                '   <li><b>信号损耗优先：</b>同轴电缆在阻抗约为 <b>77Ω</b> 时，导体损耗最小，信号衰减最低。</li>', ...
                '   <li><b>工程折中：</b>为了兼顾功率传输和低损耗，工程师选取了 30Ω 和 77Ω 的折中值。算术平均：<span class="math-text">(30+77)/2 = 53.5&Omega;</span>；几何平均：<span class="math-text">&radic;<span style="border-top:1px solid">30 &times; 77</span> &approx; 48&Omega;</span>。最终标准化为 50Ω。</li>', ...
                '</ul>', ...
                '<p>因此，该电路的任务就是消除反射（防止损坏设备）并确保最大功率传输，将任意低阻抗负载“伪装”成 50Ω 以满足源端要求。</p>', ...
                ...
                '<h2>4. 匹配原理与限制的数学推导</h2>', ...
                '<div class="box-red"><b>核心物理限制：</b>该电路只能匹配实部小于 50Ω 的负载 (<span class="math-text">R<sub>L</sub> &lt; 50&Omega;</span>)。</div>', ...
                '<h3>A. 阻抗变换方程</h3>', ...
                '<p>假设负载阻抗为纯电阻 <span class="math-text">R<sub>L</sub></span>，源阻抗为 <span class="math-text">Z<sub>0</sub> = 50&Omega;</span>。</p>', ...
                '<p><b>从源端向右看：</b>我们需要源端看到的输入阻抗 <span class="math-text">Z<sub>in</sub></span> 等于 <span class="math-text">50&Omega;</span>。</p>', ...
                '<p><b>导纳匹配条件：</b>为了实现匹配，从源端看进去的导纳 <span class="math-text">Y<sub>in</sub></span> 的实部必须等于源导纳 (<span class="math-text">1/50 &Omega;<sup>-1</sup></span>)。</p>', ...
                '<p>右侧串联部分的阻抗为 <span class="math-text">Z<sub>right</sub> = R<sub>L</sub> + jX<sub>Series</sub></span>。其导纳为：</p>', ...
                ...
                '<div class="math-block">', ...
                '   Y<sub>right</sub> = <div class="frac"><span class="num">1</span><span class="den">R<sub>L</sub> + jX<sub>Series</sub></span></div>', ...
                '   = <div class="frac"><span class="num">R<sub>L</sub> - jX<sub>Series</sub></span><span class="den">R<sub>L</sub><sup>2</sup> + X<sub>Series</sub><sup>2</sup></span></div>', ...
                '</div>', ...
                ...
                '<p>加入并联电容 <span class="math-text">C<sub>1</sub></span> 后，总导纳 <span class="math-text">Y<sub>in</sub> = j&omega;C<sub>1</sub> + Y<sub>right</sub></span>。由于并联电容只影响虚部，输入导纳的实部完全由 <span class="math-text">Y<sub>right</sub></span> 的实部决定。</p>', ...
                ...
                '<h3>B. 证明 R<sub>L</sub> 必须小于 50Ω</h3>', ...
                '<p>根据匹配要求，输入导纳实部必须等于 1/50：</p>', ...
                '<div class="math-block">', ...
                '   Re(Y<sub>in</sub>) = <div class="frac"><span class="num">R<sub>L</sub></span><span class="den">R<sub>L</sub><sup>2</sup> + X<sub>Series</sub><sup>2</sup></span></div> = <div class="frac"><span class="num">1</span><span class="den">50</span></div>', ...
                '</div>', ...
                ...
                '<p>整理该方程，求解 <span class="math-text">X<sub>Series</sub></span>：</p>', ...
                '<div class="math-block">', ...
                '   50R<sub>L</sub> = R<sub>L</sub><sup>2</sup> + X<sub>Series</sub><sup>2</sup> &rArr; X<sub>Series</sub><sup>2</sup> = 50R<sub>L</sub> - R<sub>L</sub><sup>2</sup>', ...
                '</div>', ...
                '<div class="math-block">', ...
                '   X<sub>Series</sub> = &plusmn; <span class="sqrt"><span>&radic;</span><span>50R<sub>L</sub> - R<sub>L</sub><sup>2</sup></span></span>', ...
                '</div>', ...
                ...
                '<p><b>关键推导：</b>在物理电路中，电抗 <span class="math-text">X<sub>Series</sub></span> 必须是一个实数（即平方必须非负）。因此，根号内的表达式必须大于等于 0：</p>', ...
                '<div class="math-block">', ...
                '   50R<sub>L</sub> - R<sub>L</sub><sup>2</sup> &ge; 0 &rArr; 50 - R<sub>L</sub> &ge; 0 &rArr; R<sub>L</sub> &le; 50&Omega;', ...
                '</div>', ...
                ...
                '<h3>C. 结论</h3>', ...
                '<ul>', ...
                '   <li><b>数学结论：</b>只有当负载电阻 <span class="math-text">R<sub>L</sub> &le; 50&Omega;</span> 时，方程才有实数解。如果 <span class="math-text">R<sub>L</sub> &gt; 50&Omega;</span>（例如 100Ω），物理上无法实现匹配。</li>', ...
                '   <li><b>直观规则验证：</b>L型网络的“高低法则”指出，并联元件（此处为 <span class="math-text">C<sub>1</sub></span>）必须连接在阻抗较高的一端。由于 <span class="math-text">C<sub>1</sub></span> 连接在源端（50Ω），这意味着源端必须是“高阻抗端”，即 <span class="math-text">50&Omega; &gt; R<sub>L</sub></span>。</li>', ...
                '</ul>', ...
                ...
                '<h2>5. 最终输出参数计算 (算法逻辑)</h2>', ...
                '<div class="box-green"><b>场景：</b>已知负载 <span class="math-text">Z<sub>L</sub></span>，求 <span class="math-text">C<sub>1</sub>, C<sub>2</sub></span> (反向设计)</div>', ...
                '<ol>', ...
                '   <li><b>计算所需的总串联电抗 <span class="math-text">X<sub>Total_Series</sub></span>：</b><br>利用品质因数 <span class="math-text">Q = <span class="sqrt"><span>&radic;</span><span>50/R<sub>L</sub> - 1</span></span></span>，则 <span class="math-text">X<sub>Total_Series</sub> = Q &middot; R<sub>L</sub></span>。</li>', ...
                '   <li><b>计算 <span class="math-text">C<sub>1</sub></span>：</b><br><span class="math-text">C<sub>1</sub> = <div class="frac"><span class="num">Q</span><span class="den">&omega; &middot; 50</span></div></span></li>', ...
                '   <li><b>计算 <span class="math-text">C<sub>2</sub></span>：</b><br>已知 <span class="math-text">X<sub>Total_Series</sub> = (&omega;L - <div class="frac"><span class="num">1</span><span class="den">&omega;C<sub>2</sub></span></div>) + X<sub>Load_Imag</sub></span>。<br>求解 <span class="math-text">C<sub>2</sub></span>：<div class="math-block">C<sub>2</sub> = <div class="frac"><span class="num">1</span><span class="den">&omega; [ (&omega;L + X<sub>Load_Imag</sub>) - X<sub>Total_Series</sub> ]</span></div></div></li>', ...
                '</ol>', ...
                ...
                '<h2>6. 工作频率范围 (1 ~ 133 MHz)</h2>', ...
                '<ul>', ...
                '   <li><b>下限限制 (1 MHz)：</b>频率过低会导致所需的电感值 <span class="math-text">L</span> 巨大（毫亨级），体积大且损耗高。</li>', ...
                '   <li><b>上限限制 (133 MHz)：</b>频率过高时，集总参数模型失效。当元件尺寸接近波长 <span class="math-text">1/10</span> 时，寄生效应（自谐振）导致公式不准确，应使用微带线。</li>', ...
                '</ul>', ...
                ...
                '<h2>7. 总结</h2>', ...
                '<p><b>设计核心：</b>利用并联电容 <span class="math-text">C<sub>1</sub></span> 提升阻抗实部（从 <span class="math-text">R<sub>L</sub></span> 提升到 50），利用串联 <span class="math-text">L+C<sub>2</sub></span> 组合调节虚部以抵消并联电容带来的容性电纳。</p>', ...
                '<p><b>为何加 <span class="math-text">C<sub>2</sub></span>？</b>标准的 L 型只有 <span class="math-text">L</span>。加入 <span class="math-text">C<sub>2</sub></span> 构成了 LC 串联谐振支路，使得在不改变物理电感 <span class="math-text">L</span> 的情况下，通过调节旋钮（可变电容）就能改变这一支路的等效电抗，极大地拓宽了匹配范围。</p>', ...
                '</div></body></html>'];

            app.HTML.HTMLSource = htmlContent;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.HTML = uihtml(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Readme_L_Right

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end