classdef Readme_T < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        HTML        matlab.ui.control.HTML
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. 设置窗口标题
            app.UIFigure.Name = 'T型电路 - 三元件匹配 - 高Q值/宽范围专家';
            
            % 2. 准备 HTML 内容
            % 【纯离线版】基于 T型电路 的物理逻辑编写
            htmlContent = [ ...
                '<!DOCTYPE html>', ...
                '<html><head>', ...
                '<meta charset="utf-8">', ...
                '<style>', ...
                '   body { font-family: "Segoe UI", "Microsoft YaHei", sans-serif; padding: 30px; line-height: 1.6; color: #333; background-color: #fdfdfd; }', ...
                '   .container { max-width: 100%; margin: 0 auto; }', ...
                '   h1 { color: #0072BD; border-bottom: 3px solid #0072BD; padding-bottom: 10px; margin-top: 0; text-align: center; }', ...
                '   h2 { color: #D95319; margin-top: 40px; border-left: 6px solid #D95319; padding-left: 10px; background: #fff5f0; padding-top:5px; padding-bottom:5px;}', ...
                '   h3 { color: #EDB120; margin-top: 25px; font-weight: bold; border-bottom: 1px dashed #ccc; display: inline-block; }', ...
                '   p { margin-bottom: 12px; text-align: justify; }', ...
                '   ul { margin-bottom: 15px; padding-left: 20px; }', ...
                '   li { margin-bottom: 6px; }', ...
                '   /* 信息框样式 */', ...
                '   .box-blue { background-color: #e8f4f8; border: 1px solid #bce8f1; padding: 15px; border-radius: 5px; color: #31708f; margin: 15px 0; }', ...
                '   .box-red { background-color: #f2dede; border: 1px solid #ebccd1; padding: 15px; border-radius: 5px; color: #a94442; margin: 15px 0; }', ...
                '   .box-green { background-color: #dff0d8; border: 1px solid #d6e9c6; padding: 15px; border-radius: 5px; color: #3c763d; margin: 15px 0; }', ...
                '   /* 离线公式样式 */', ...
                '   .math-text { font-family: "Times New Roman", serif; font-style: italic; font-size: 1.1em; padding: 0 2px; }', ...
                '   .math-block { background-color: #fff; padding: 15px; border-radius: 4px; text-align: center; border: 1px solid #eee; margin: 15px 0; overflow-x: auto; font-family: "Times New Roman", serif; font-size: 1.2em; }', ...
                '   /* 分数样式 (CSS 实现) */', ...
                '   .frac { display: inline-block; vertical-align: middle; text-align: center; font-size: 0.9em; margin: 0 5px; }', ...
                '   .frac .num { border-bottom: 1px solid #000; display: block; padding: 0 2px; }', ...
                '   .frac .den { display: block; padding: 0 2px; }', ...
                '   /* 根号样式 */', ...
                '   .sqrt { white-space: nowrap; }', ...
                '   .sqrt span:first-child { font-size: 1.2em; vertical-align: text-bottom; }', ...
                '   .sqrt span:last-child { border-top: 1px solid #000; padding-top: 1px; }', ...
                '</style></head><body>', ...
                '<div class="container">', ...
                ...
                '<h1>T型电路 设计原理详解</h1>', ...
                ...
                '<h2>1. 拓扑结构：三元件的“T”字形网络</h2>', ...
                '<p>T型匹配网络由三个主要部分组成，形状类似字母“T” 。</p>', ...
                '<h3>电路构成：</h3>', ...
                '<ul>', ...
                '   <li><b>源端串联臂：</b>电容 <span class="math-text">C<sub>3</sub></span>。</li>', ...
                '   <li><b>负载端串联臂：</b>电容 <span class="math-text">C<sub>1</sub></span>。</li>', ...
                '   <li><b>中间并联臂：</b>由电感 <span class="math-text">L</span> 和电容 <span class="math-text">C<sub>2</sub></span> 串联组成，并联在中间节点接地。</li>', ...
                '</ul>', ...
                '<div class="box-blue"><b>结构特性：</b>T型网络可以看作是两个L型网络的“背靠背”连接（两个L型的并联臂合并）。这种结构引入了一个<b>高阻抗的中间节点</b>。</div>', ...
                ...
                '<h2>2. 设计核心：虚拟中间电阻与Q值控制</h2>', ...
                '<p>与L型网络（Q值不可调）不同，T型网络多了一个自由度，允许我们在匹配阻抗的同时选择<b>加载品质因数 Q</b>。</p>', ...
                '<h3>虚拟电阻法 (Virtual Resistor Method)：</h3>', ...
                '<p>我们将电路从中间切开，看作左右两个L型网络：</p>', ...
                '<ul>', ...
                '   <li><b>左侧L型：</b>将源阻抗 <span class="math-text">R<sub>S</sub></span> (50Ω) 提升匹配到一个虚拟的高阻抗 <span class="math-text">R<sub>virtual</sub></span>。</li>', ...
                '   <li><b>右侧L型：</b>将负载阻抗 <span class="math-text">R<sub>L</sub></span> 提升匹配到同一个虚拟阻抗 <span class="math-text">R<sub>virtual</sub></span>。</li>', ...
                '</ul>', ...
                '<div class="box-red"><b>关键条件：</b>为了实现实数解，虚拟电阻 <span class="math-text">R<sub>virtual</sub></span> 必须<b>大于</b>源电阻和负载电阻中的较大者。<br>即 <span class="math-text">R<sub>virtual</sub> > max(R<sub>S</sub>, R<sub>L</sub>)</span>。</div>', ...
                ...
                '<h2>3. 物理限制与适用范围</h2>', ...
                '<p>T型网络非常灵活，理论上可以匹配任何实部小于 <span class="math-text">R<sub>virtual</sub></span> 的负载。</p>', ...
                '<ul>', ...
                '   <li><b>适用场景：</b>通常用于连接两个低阻抗系统，或者需要较高Q值以获得更好滤波特性的场合（窄带匹配）。</li>', ...
                '   <li><b>并联臂 (L+C2) 的作用：</b>类似于L型电路中的设计，这里利用 <span class="math-text">L</span> 和 <span class="math-text">C<sub>2</sub></span> 构成串联谐振支路作为并联臂，使得该臂的等效电抗 <span class="math-text">X<sub>shunt</sub></span> 可以在感性和容性之间调节，极大拓宽了匹配范围。</li>', ...
                '</ul>', ...
                ...
                '<h2>4. 算法逻辑：反向设计 (求 C1, C2, C3)</h2>', ...
                '<div class="box-green"><b>输入：</b>负载 <span class="math-text">Z<sub>L</sub></span>，源 <span class="math-text">Z<sub>0</sub>=50&Omega;</span>，选定的Q值（或由 <span class="math-text">R<sub>virtual</sub></span> 决定）。</div>', ...
                '<p>假设我们要匹配到一个选定的高阻 <span class="math-text">R<sub>v</sub></span> (Virtual Resistance)：</p>', ...
                '<ol>', ...
                '   <li><b>计算源端转换 (左侧)：</b><br>品质因数 <span class="math-text">Q<sub>S</sub> = <span class="sqrt"><span>&radic;</span><span>R<sub>v</sub>/50 - 1</span></span></span>。<br>源端串联电抗 <span class="math-text">X<sub>S_series</sub></span> 和并联电抗 <span class="math-text">X<sub>S_shunt</sub></span> 由 <span class="math-text">Q<sub>S</sub></span> 确定。</li>', ...
                '   <li><b>计算负载端转换 (右侧)：</b><br>品质因数 <span class="math-text">Q<sub>L</sub> = <span class="sqrt"><span>&radic;</span><span>R<sub>v</sub>/R<sub>L</sub> - 1</span></span></span>。<br>负载端串联电抗 <span class="math-text">X<sub>L_series</sub></span> 和并联电抗 <span class="math-text">X<sub>L_shunt</sub></span> 由 <span class="math-text">Q<sub>L</sub></span> 确定。</li>', ...
                '   <li><b>元件值求解：</b><br>', ...
                '       <ul>', ...
                '           <li><span class="math-text">C<sub>3</sub></span> 提供 <span class="math-text">X<sub>S_series</sub></span>。</li>', ...
                '           <li><span class="math-text">C<sub>1</sub></span> 提供 <span class="math-text">X<sub>L_series</sub></span>。</li>', ...
                '           <li>中间臂 (<span class="math-text">L+C<sub>2</sub></span>) 需要提供的总电纳 <span class="math-text">B<sub>total</sub> = B<sub>S_shunt</sub> + B<sub>L_shunt</sub></span>。通过调节 <span class="math-text">C<sub>2</sub></span> 来满足这一条件。</li>', ...
                '       </ul>', ...
                '   </li>', ...
                '</ol>', ...
                ...
                '<h2>5. 总结</h2>', ...
                '<p>T型电路通过引入第三个元件和“中间高阻节点”的概念，提供了比L型更强的灵活性。它不仅能进行阻抗变换，还能作为带通滤波器使用。本程序中的设计利用了可变电容 <span class="math-text">C<sub>1</sub>, C<sub>2</sub>, C<sub>3</sub></span> 配合固定电感 <span class="math-text">L</span>，实现了广泛的调谐能力。</p>', ...
                '</div></body></html>'];
            app.HTML.HTMLSource = htmlContent;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.HTML = uihtml(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Readme_T

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end