classdef Readme_pi1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        HTML        matlab.ui.control.HTML
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. 设置窗口标题
            app.UIFigure.Name = 'Pi (I) 型电路 - 混合匹配网络设计原理';
            
            % 2. 准备 HTML 内容
            % 基于 C3(并)-[L+C2](串)-C1(并) 拓扑编写
            htmlContent = [ ...
                '<!DOCTYPE html>', ...
                '<html><head>', ...
                '<meta charset="utf-8">', ...
                '<style>', ...
                '   body { font-family: "Segoe UI", "Microsoft YaHei", sans-serif; padding: 30px; line-height: 1.6; color: #333; background-color: #fdfdfd; }', ...
                '   h1 { color: #7E2F8E; border-bottom: 3px solid #7E2F8E; padding-bottom: 10px; text-align: center; }', ... % 紫色主题
                '   h2 { color: #0072BD; margin-top: 30px; border-left: 5px solid #0072BD; padding-left: 10px; background: #f0f8ff; padding: 5px 0 5px 10px; }', ...
                '   h3 { color: #D95319; margin-top: 20px; font-weight: bold; border-bottom: 1px dashed #ccc; display: inline-block; }', ...
                '   p { margin-bottom: 12px; text-align: justify; }', ...
                '   ul { margin-bottom: 15px; padding-left: 20px; }', ...
                '   li { margin-bottom: 6px; }', ...
                '   .box-gray { background-color: #f9f9f9; border: 1px solid #ddd; padding: 15px; border-radius: 5px; margin: 15px 0; }', ...
                '   .box-highlight { background-color: #fff8e1; border: 1px solid #ffe0b2; padding: 15px; border-radius: 5px; color: #e65100; margin: 15px 0; }', ...
                '   .math { font-family: "Times New Roman", serif; font-style: italic; font-weight: bold; padding: 0 2px; }', ...
                '   .formula { background-color: #fff; padding: 10px; text-align: center; border: 1px solid #eee; margin: 10px 0; font-family: "Times New Roman", serif; font-size: 1.1em; }', ...
                '</style></head><body>', ...
                ...
                '<h1>&pi; 型 (Type I) 电路设计原理</h1>', ...
                ...
                '<h2>1. 拓扑结构分析</h2>', ...
                '<p>本工具演示的是一种改进型的 &pi; (Pi) 网络，通常被称为 <b>C-LC-C 混合结构</b>。与标准的三元件 &pi; 网络不同，它在串联臂中引入了额外的调谐自由度。</p>', ...
                '<div class="box-gray">', ...
                '   <b>电路构成：</b>', ...
                '   <ul>', ...
                '       <li><b>源端 (Source)：</b>并联电容 <span class="math">C<sub>3</sub></span>。</li>', ...
                '       <li><b>中间串联臂：</b>电感 <span class="math">L</span> 与电容 <span class="math">C<sub>2</sub></span> 串联。</li>', ...
                '       <li><b>负载端 (Load)：</b>并联电容 <span class="math">C<sub>1</sub></span>。</li>', ...
                '   </ul>', ...
                '</div>', ...
                ...
                '<h2>2. 物理机制：阻抗平衡法</h2>', ...
                '<p>对于这种四元件网络（其中 L 和 C3 通常作为固定或预设参数），匹配过程可以理解为寻找 <span class="math">C<sub>1</sub></span> 和 <span class="math">C<sub>2</sub></span> 以满足实部和虚部的平衡。</p>', ...
                ...
                '<h3>Step 1: 源端状态确定</h3>', ...
                '<p>由于 <span class="math">C<sub>3</sub></span> 是预设的，源阻抗 (50&Omega;) 并联 <span class="math">C<sub>3</sub></span> 后，形成了一个确定的复阻抗 <span class="math">Z<sub>S_eff</sub></span>。</p>', ...
                '<div class="formula">Z<sub>S_eff</sub> = R<sub>S_eff</sub> + jX<sub>S_eff</sub> = (50) || (C<sub>3</sub>)</div>', ...
                ...
                '<h3>Step 2: 电阻匹配 (求解 C1)</h3>', ...
                '<p>为了实现最大功率传输，我们需要负载端（负载 <span class="math">Z<sub>L</sub></span> 并联 <span class="math">C<sub>1</sub></span>）变换后的等效电阻部分，严格等于源端的有效电阻 <span class="math">R<sub>S_eff</sub></span>。</p>', ...
                '<div class="box-highlight">', ...
                '   程序通过数值方法或解析公式，调节 <span class="math">C<sub>1</sub></span>，使得：<br>', ...
                '   Real( Z<sub>L</sub> || C<sub>1</sub> ) = Real( Z<sub>S_eff</sub> )', ...
                '</div>', ...
                ...
                '<h3>Step 3: 电抗抵消 (求解 C2)</h3>', ...
                '<p>当电阻部分匹配后，两端各自会剩余一定的电抗（虚部）。中间的串联臂 (<span class="math">L + C<sub>2</sub></span>) 必须提供恰好相反的电抗来抵消这些剩余虚部。</p>', ...
                '<p>串联臂需要的总电抗 <span class="math">X<sub>series</sub></span> 确定后，由于 <span class="math">L</span> 已知，即可解出 <span class="math">C<sub>2</sub></span>：</p>', ...
                '<div class="formula">X<sub>C2</sub> = &omega;L - X<sub>series</sub> &nbsp;&rarr;&nbsp; C<sub>2</sub> = -1 / (&omega; X<sub>C2</sub>)</div>', ...
                ...
                '<h2>3. 为什么使用 L+C2 串联？</h2>', ...
                '<p>标准的 &pi; 型网络中间只有电感 L。但在本电路中，<b>L 与 C2 串联</b> 构成了串联臂：</p>', ...
                '<ul>', ...
                '   <li><b>扩展匹配范围：</b>串联臂的总电抗可以是感性（L主导）也可以是容性（C2主导）。这使得该网络能匹配标准 &pi; 型网络无法匹配的负载区域（禁区）。</li>', ...
                '   <li><b>谐振控制：</b>在特定频率下，L 和 C2 可以发生串联谐振，提供极低的串联阻抗。</li>', ...
                '</ul>', ...
                ...
                '<h2>4. 参数计算流程 (反向设计)</h2>', ...
                '<p>在本 App 的“反向计算”模式中：</p>', ...
                '<ol>', ...
                '   <li>用户输入 <b>工作频率 f</b>、<b>目标负载 Z<sub>L</sub></b>。</li>', ...
                '   <li>用户选定 <b>电感 L</b> 和 <b>源端电容 C<sub>3</sub></b>。</li>', ...
                '   <li>点击计算后，算法自动求解所需的 <b>C<sub>1</sub></b> (调节电阻匹配) 和 <b>C<sub>2</sub></b> (调节电抗平衡)。</li>', ...
                '</ol>', ...
                '</body></html>'];
            app.HTML.HTMLSource = htmlContent;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.HTML = uihtml(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Readme_pi1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end