classdef Readme_pi2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        HTML        matlab.ui.control.HTML
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. 设置窗口标题
            app.UIFigure.Name = 'Pi (II) 型电路 - 固定负载电容匹配策略';
            
            % 2. 准备 HTML 内容
            % 核心逻辑：负载端 C3 固定 -> 求解源端 C1 和中间 C2
            htmlContent = [ ...
                '<!DOCTYPE html>', ...
                '<html><head>', ...
                '<meta charset="utf-8">', ...
                '<style>', ...
                '   body { font-family: "Segoe UI", "Microsoft YaHei", sans-serif; padding: 30px; line-height: 1.6; color: #333; background-color: #fdfdfd; }', ...
                '   .container { max-width: 100%; margin: 0 auto; }', ...
                '   h1 { color: #0072BD; border-bottom: 3px solid #0072BD; padding-bottom: 10px; margin-top: 0; text-align: center; }', ...
                '   h2 { color: #D95319; margin-top: 40px; border-left: 6px solid #D95319; padding-left: 10px; background: #fff5f0; padding-top:5px; padding-bottom:5px;}', ...
                '   h3 { color: #EDB120; margin-top: 25px; font-weight: bold; border-bottom: 1px dashed #ccc; display: inline-block; }', ...
                '   p { margin-bottom: 12px; text-align: justify; }', ...
                '   ul { margin-bottom: 15px; padding-left: 20px; }', ...
                '   li { margin-bottom: 6px; }', ...
                '   /* 样式定义 */', ...
                '   .box-blue { background-color: #e8f4f8; border: 1px solid #bce8f1; padding: 15px; border-radius: 5px; color: #31708f; margin: 15px 0; }', ...
                '   .box-green { background-color: #dff0d8; border: 1px solid #d6e9c6; padding: 15px; border-radius: 5px; color: #3c763d; margin: 15px 0; }', ...
                '   .math-text { font-family: "Times New Roman", serif; font-style: italic; font-size: 1.1em; padding: 0 2px; }', ...
                '   .formula-box { background-color: #fff; padding: 10px; text-align: center; border: 1px solid #eee; margin: 10px 0; font-family: "Times New Roman", serif; font-size: 1.2em; }', ...
                '</style></head><body>', ...
                '<div class="container">', ...
                ...
                '<h1>&pi; 型 (Type II) 电路原理</h1>', ...
                ...
                '<h2>1. 拓扑结构：源端调节型 Pi 网络</h2>', ...
                '<p>Pi (II) 型电路是 Pi 型网络的另一种应用形态。与 Type I 不同，它的<b>固定元件位于负载端</b>，调谐元件位于源端和中间。</p>', ...
                '<h3>电路构成：</h3>', ...
                '<ul>', ...
                '   <li><b>源端 (Source)：</b>并联可变电容 <span class="math-text">C<sub>1</sub></span> (待求)。</li>', ...
                '   <li><b>中间串联臂：</b>固定电感 <span class="math-text">L</span> 串联可变电容 <span class="math-text">C<sub>2</sub></span>。</li>', ...
                '   <li><b>负载端 (Load)：</b>并联固定电容 <span class="math-text">C<sub>3</sub></span> (已知)。</li>', ...
                '</ul>', ...
                '<div class="box-blue"><b>应用场景：</b>当负载本身具有较大的寄生电容（等效为 <span class="math-text">C<sub>3</sub></span>），或者负载端必须连接特定的滤波电容时，我们无法调节负载端的并联支路。此时，必须通过调节<b>源端并联电容</b>和<b>中间串联支路</b>来实现 50&Omega; 匹配。</div>', ...
                ...
                '<h2>2. 设计思路 (反向计算)</h2>', ...
                '<p>我们的目标是：已知 <span class="math-text">Z<sub>Load</sub></span>, <span class="math-text">L</span>, <span class="math-text">C<sub>3</sub></span>，求 <span class="math-text">C<sub>1</sub></span>, <span class="math-text">C<sub>2</sub></span>。</p>', ...
                ...
                '<h3>步骤 1: 负载端归一化</h3>', ...
                '<p>首先，将负载阻抗 <span class="math-text">Z<sub>L</sub></span> 与固定的 <span class="math-text">C<sub>3</sub></span> 进行并联，得到一个等效的负载阻抗 <span class="math-text">Z<sub>L_eff</sub></span>。</p>', ...
                '<div class="formula-box">Y<sub>L_eff</sub> = 1/Z<sub>L</sub> + j&omega;C<sub>3</sub> &nbsp;&rarr;&nbsp; Z<sub>L_eff</sub> = 1/Y<sub>L_eff</sub></div>', ...
                ...
                '<h3>步骤 2: 电阻匹配 (求解 C1)</h3>', ...
                '<p>现在问题转化为：如何调节源端的 <span class="math-text">C<sub>1</sub></span>，使得源端阻抗变换后的<b>电阻实部</b>，与负载端等效电阻相匹配。</p>', ...
                '<p>我们需要找到一个 <span class="math-text">C<sub>1</sub></span>，使得源端并联结构 (50&Omega; || <span class="math-text">C<sub>1</sub></span>) 转换通过串联臂后，能“看到”负载的实部电阻。</p>', ...
                '<div class="box-green"><b>算法核心：</b>通过解析方程，使得 <span class="math-text">Real(Z<sub>Source_Net</sub>) = Real(Z<sub>L_eff</sub>)</span>。此方程通常有两个解，程序会优选正实数解作为 <span class="math-text">C<sub>1</sub></span>。</div>', ...
                ...
                '<h3>步骤 3: 电抗抵消 (求解 C2)</h3>', ...
                '<p>当电阻部分匹配后，两端会剩余一定的虚部（电抗）。中间的串联臂 (<span class="math-text">L + C<sub>2</sub></span>) 必须负责抵消这些剩余的电抗。</p>', ...
                '<p>已知电感 <span class="math-text">L</span>，计算所需的总串联电抗 <span class="math-text">X<sub>series</sub></span>，进而求出 <span class="math-text">C<sub>2</sub></span>：</p>', ...
                '<div class="formula-box">C<sub>2</sub> = 1 / [&omega; &middot; (&omega;L - X<sub>required</sub>)]</div>', ...
                ...
                '<h2>3. 总结</h2>', ...
                '<p>Pi (II) 型电路展示了匹配网络的灵活性。通过改变固定元件的位置（从源端移至负载端），我们可以适应不同的工程约束条件。虽然数学求解过程比 L 型电路复杂（涉及四次元件方程），但物理本质依然是<b>实部匹配</b>与<b>虚部抵消</b>。</p>', ...
                '</div></body></html>'];
            app.HTML.HTMLSource = htmlContent;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.HTML = uihtml(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Readme_pi2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end